classdef CurrentGuiHomeTab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcometoSASULabel              matlab.ui.control.Label
        HomesteadFLButton_2             matlab.ui.control.Button
        ChelanCountyWAButton_2          matlab.ui.control.Button
        GreatPlainsWestMTButton_2       matlab.ui.control.Button
        DemoModeLabel_2                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        TextArea_3                      matlab.ui.control.TextArea
        WhatisSASULabel                 matlab.ui.control.Label
        TutorialLabel                   matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        TextArea_4                      matlab.ui.control.TextArea
        Tab2                            matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        HTML                            matlab.ui.control.HTML
        InputDataTab                    matlab.ui.container.Tab
        LoadSolarDataButton             matlab.ui.control.Button
        LoadDemandDataButton            matlab.ui.control.Button
        LoadTemperatureDataButton       matlab.ui.control.Button
        LoadDataLabel                   matlab.ui.control.Label
        DataTimeIncrementButtonGroup    matlab.ui.container.ButtonGroup
        MinutesButton                   matlab.ui.control.ToggleButton
        HoursButton                     matlab.ui.control.ToggleButton
        TextArea_2                      matlab.ui.control.TextArea
        SolarArraySizeMWEditFieldLabel  matlab.ui.control.Label
        SolarArraySizeMWEditField       matlab.ui.control.NumericEditField
        PredictedYearlyLoadGrowthLabel  matlab.ui.control.Label
        PredictedYearlyLoadGrowthEditField  matlab.ui.control.NumericEditField
        SolarGenerationCheckBox         matlab.ui.control.CheckBox
        ElectricityDemandCheckBox       matlab.ui.control.CheckBox
        TemperatureCheckBox             matlab.ui.control.CheckBox
        DatatoGraphLabel                matlab.ui.control.Label
        StartDatetographSliderLabel     matlab.ui.control.Label
        StartDatetographSlider          matlab.ui.control.Slider
        EndDatetographSliderLabel       matlab.ui.control.Label
        EndDatetographSlider            matlab.ui.control.Slider
        MaximumSliderLabel              matlab.ui.control.Label
        MaximumSlider                   matlab.ui.control.Slider
        MinimumSliderLabel              matlab.ui.control.Label
        MinimumSlider                   matlab.ui.control.Slider
        TemperatureUnitsButtonGroup     matlab.ui.container.ButtonGroup
        CelsiusButton                   matlab.ui.control.ToggleButton
        FahrenheitButton                matlab.ui.control.ToggleButton
        UIAxes                          matlab.ui.control.UIAxes
        SystemSpecificationsTab         matlab.ui.container.Tab
        ESSAlgorithmButtonGroup         matlab.ui.container.ButtonGroup
        TimeBasedButton                 matlab.ui.control.ToggleButton
        LoadBasedButton                 matlab.ui.control.ToggleButton
        BatteryReplacementButtonGroup   matlab.ui.container.ButtonGroup
        YearsButton                     matlab.ui.control.ToggleButton
        CyclesButton                    matlab.ui.control.ToggleButton
        MinimumSOHButton                matlab.ui.control.ToggleButton
        ThroughputMWhButton             matlab.ui.control.ToggleButton
        ESSDepthofDischargeLabel        matlab.ui.control.Label
        ESSDepthofDischargeEditField    matlab.ui.control.NumericEditField
        ESSDurationhoursEditFieldLabel  matlab.ui.control.Label
        ESSDurationhoursEditField       matlab.ui.control.NumericEditField
        ESSInitialEnergyofMaximumEditFieldLabel  matlab.ui.control.Label
        ESSInitialEnergyofMaximumEditField  matlab.ui.control.NumericEditField
        SubstationPlannedRatingMWEditFieldLabel  matlab.ui.control.Label
        SubstationPlannedRatingMWEditField  matlab.ui.control.NumericEditField
        UnitsSwitchLabel                matlab.ui.control.Label
        UnitsSwitch                     matlab.ui.control.Switch
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        EditField2                      matlab.ui.control.NumericEditField
        YearlyESSCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlyESSCapacityLossEditField  matlab.ui.control.NumericEditField
        YearlySolarCapacityLossEditFieldLabel  matlab.ui.control.Label
        YearlySolarCapacityLossEditField  matlab.ui.control.NumericEditField
        MonthlyESSchargeLossEditFieldLabel  matlab.ui.control.Label
        MonthlyESSchargeLossEditField   matlab.ui.control.NumericEditField
        InverterEfficiencyLabel         matlab.ui.control.Label
        InverterEfficiencyEditField     matlab.ui.control.NumericEditField
        DCDCConverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        DCDCConverterEfficiencyEditField  matlab.ui.control.NumericEditField
        ESSRoundTripEfficiencyLabel     matlab.ui.control.Label
        ESSRoundTripEfficiencyEditField  matlab.ui.control.NumericEditField
        YearsperHardwareReplacementEditFieldLabel  matlab.ui.control.Label
        YearsperHardwareReplacementEditField  matlab.ui.control.NumericEditField
        HardwareReplacementButtonGroup  matlab.ui.container.ButtonGroup
        YearsButton_2                   matlab.ui.control.ToggleButton
        ThroughputMWhButton_2           matlab.ui.control.ToggleButton
        CostVariablesTab                matlab.ui.container.Tab
        OptimizationTab                 matlab.ui.container.Tab
        ResultsTab                      matlab.ui.container.Tab
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        Slider2Label                    matlab.ui.control.Label
        Slider2                         matlab.ui.control.Slider
        AboutthistoolTab                matlab.ui.container.Tab
        ContributersPanel               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        
        %data
        solarData % solar data, single-column CSV
        demandData % solar data, single-column CSV
        tempData % temperature data, single-column CSV
        isGraphSolar % logical value to graph solar generation data
        isGraphDemand % logical value to graph electricity demand data
        isGraphTemperature % logical value to graph temperature data
        startDateGraph % index of the first data point graphed
        endDateGraph % index of the last data point graphed
        dataLength % amount of indices in data entered 
        graphYMax % maximum y value graphed, as % of largest data point entered
        graphYMin % minumum y value graphed, as % of largest data point entered
        dataMax % maximum value of all solar and demand data entered
        tempUnit % 'Celsius' or 'Fahrenheit'
    end
    
    %helper functions
    methods (Access = private)
        
        function result = initializeSlider(app)
            tickspace = floor(app.dataLength/12);
            
            app.StartDatetographSlider.Limits = [1 app.dataLength];
            app.StartDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.StartDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.StartDatetographSlider.Value = 1;
            app.startDateGraph = 1;
            
            app.EndDatetographSlider.Limits = [1 app.dataLength];
            app.EndDatetographSlider.MajorTicks = [1 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace 12*tickspace];
            app.EndDatetographSlider.MajorTickLabels = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', ' '};
            app.EndDatetographSlider.Value = app.dataLength;
            app.endDateGraph = app.dataLength;
            
            app.dataMax = max([max(app.demandData) max(app.solarData) max(app.tempData)]);
            app.MinimumSlider.Value = 1;
            app.graphYMin = 1;
            app.MaximumSlider.Value = 100;
            app.graphYMax = 100;
            
            result = 1;
        end
        
        function result = initializeAxes(app,tempUnit)
            tickspace = floor(app.dataLength/12);
            degreesUnits = strcat('Degrees ',tempUnit);
            yyaxis(app.UIAxes, 'left');
            ylabel(app.UIAxes,'MWh');
            yyaxis(app.UIAxes,'right');
            ylabel(app.UIAxes,degreesUnits)
            title(app.UIAxes, 'Data Entered');
            xticks(app.UIAxes,[0 tickspace 2*tickspace 3*tickspace 4*tickspace 5*tickspace 6*tickspace 7*tickspace 8*tickspace 9*tickspace 10*tickspace 11*tickspace]);
            xticklabels(app.UIAxes,{'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'});
            result = 1;
        end
        
        function newTempData = fahrenheitToCelsius(~,tempData)
            tempData = tempData - 32;
            newTempData = tempData .* (5/9);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
        end

        % Button pushed function: LoadSolarDataButton
        function LoadSolarDataButtonPushed(app, event)
            [solarFile,solarPath] = uigetfile('*.csv','Select a single-column CSV file of Solar Generation data');
            
            if isequal(solarFile, 0)
                return;
            end
            
            solarFile = strcat(solarPath,solarFile);
            app.solarData = readmatrix(solarFile);
            
            app.solarData(isnan(app.solarData)) = 0;
            
            if min(size(app.solarData)) > 1 || isequal(app.solarData,0)
                clear app.solarData;
                msgbox('The solar data entered was not a single-column CSV file. Please enter a single-column CSV file', 'Error', 'error');
            end
            
            if ~isempty(app.demandData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    err = questdlg('The solar data entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.solarData;
                    end
                end
            end
            
            if ~isempty(app.tempData)
                if ~isequal(length(app.tempData),length(app.solarData))
                    clear app.solarData;
                    err = questdlg('The solar data entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.tempData)
                                app.solarData = app.solarData(1:length(app.tempData));
                            else
                                app.tempData = app.tempData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.solarData;
                    end
                end
            end
            
            app.dataLength = length(app.solarData);
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadDemandDataButton
        function LoadDemandDataButtonPushed(app, event)
            [demandFile,demandPath] = uigetfile('*.csv','Select a single-column CSV file of Demand data');
            
            if isequal(demandFile, 0)
                return;
            end
            
            demandFile = strcat(demandPath,demandFile);
            app.demandData = readmatrix(demandFile);
            
            app.demandData(isnan(app.demandData)) = 0;
            
            if min(size(app.demandData)) > 1 || isequal(app.demandData,0)
                clear app.demandData;
                msgbox('The input was not a single-column CSV file. Please input a single-column CSV file', 'Error', 'error');
            end
            
            if ~isempty(app.solarData)
                if ~isequal(length(app.demandData),length(app.solarData))
                    clear app.demandData;
                    err = questdlg('The electricity demand data entered is not the same length as the solar data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.solarData) > length(app.demandData)
                                app.solarData = app.solarData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.solarData));
                            end
                        case 'Enter New Data'
                            clear app.demandData;
                    end
                end
            end
            
            if ~isempty(app.tempData)
                if ~isequal(length(app.tempData),length(app.demandData))
                    clear app.demandData;
                    err = questdlg('The electricity demand data entered is not the same length as the temperature data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.demandData) > length(app.tempData)
                                app.demandData = app.demandData(1:length(app.tempData));
                            else
                                app.tempData = app.tempData(1:length(app.demandData));
                            end
                        case 'Enter New Data'
                            clear app.demandData;
                    end
                end
            end
            
            app.dataLength = length(app.demandData);
            
            initializeSlider(app);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadTemperatureDataButton
        function LoadTemperatureDataButtonPushed(app, event)
            [tempFile,tempPath] = uigetfile('*.csv','Select a single-column CSV file of Temperature data');
            
            if isequal(tempFile, 0)
                return;
            end
            
            tempFile = strcat(tempPath,tempFile);
            app.tempData = readmatrix(tempFile);
            
            app.loadData(isnan(app.loadData)) = 0;
            
            if min(size(app.tempData)) > 1 || isequal(app.tempData,0)
                clear app.tempData;
                msgbox('The input was not a single-column CSV file. Please input a single-column CSV file', 'Error', 'error');
            end
            
            if ~isempty(app.demandData)
                if ~isequal(length(app.demandData),length(app.tempData))
                    clear app.tempData;
                    err = questdlg('The temperature entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.tempData) > length(app.demandData)
                                app.tempData = app.tempData(1:length(app.demandData));
                            else
                                app.demandData = app.demandData(1:length(app.tempData));
                            end
                        case 'Enter New Data'
                            clear app.tempData;
                    end
                end
            end
            
            if ~isempty(app.solarData)
                if ~isequal(length(app.tempData),length(app.solarData))
                    clear app.tempData;
                    err = questdlg('The solar data entered is not the same length as the electricity demand data entered. Would you like to truncate the longer data set or enter new data?','Error','Truncate','Enter new data','Truncate');
                    switch err
                        case 'Truncate'
                            if length(app.tempData) > length(app.solarData)
                                app.tempData = app.tempData(1:length(app.solarData));
                            else
                                app.solarData = app.solarData(1:length(app.tempData));
                            end
                        case 'Enter New Data'
                            clear app.tempData;
                    end
                end
            end
            
            app.dataLength = length(app.tempData);
            
            initializeSlider(app);
            
            if app.tempUnit == 'Fahrenheit'
                app.tempData = fahrenheitToCelsius(app,app.tempData);
            end
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: SolarGenerationCheckBox
        function SolarGenerationCheckBoxValueChanged(app, event)
            app.isGraphSolar = app.SolarGenerationCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphSolar
                initializeAxes(app,app.tempUnit);
                yyaxis(app.UIAxes, 'left');
                plot(app.UIAxes,app.solarData,'-y'); 
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes,app.demandData, '-r');
                end
                if app.isGraphTemperature
                    yyaxis(app.UIAxes, 'right');
                    plot(app.UIAxes,app.tempData, '-b');
                end
            end
        end

        % Value changed function: ElectricityDemandCheckBox
        function ElectricityDemandCheckBoxValueChanged(app, event)
            app.isGraphDemand = app.ElectricityDemandCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphDemand
                initializeAxes(app,app.tempUnit);
                yyaxis(app.UIAxes, 'left');
                plot(app.UIAxes,app.demandData,'-r'); 
            else
                cla(app.UIAxes);
                if app.isGraphTemperature
                    yyaxis(app.UIAxes, 'right');
                    plot(app.UIAxes,app.tempData,'-b');
                end
                if app.isGraphSolar
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes,app.solarData,'-y');
                end
            end
        end

        % Value changed function: TemperatureCheckBox
        function TemperatureCheckBoxValueChanged(app, event)
            app.isGraphTemperature = app.TemperatureCheckBox.Value;
            hold(app.UIAxes,"on");
            
            if app.isGraphTemperature
                initializeAxes(app,app.tempUnit);
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes,app.tempData,'-b');
            else
                cla(app.UIAxes);
                if app.isGraphDemand
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes,app.demandData,'-r');
                end
                if app.isGraphSolar
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes,app.solarData,'-y');
                end
            end
        end

        % Value changing function: StartDatetographSlider
        function StartDatetographSliderValueChanging(app, event)
            app.startDateGraph = event.Value;
            
            if app.startDateGraph > app.endDateGraph - 10
                app.startDateGraph = app.endDateGraph - 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.startDateGraph >= app.dataLength - 10
                app.startDateGraph = app.dataLength - 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData,'-y');
            end
            if app.isGraphTemperature
                plot(app.UIAxes,app.tempData,'-b');
            end
        end

        % Value changing function: EndDatetographSlider
        function EndDatetographSliderValueChanging(app, event)
            app.endDateGraph = event.Value;
            
            if app.startDateGraph > app.endDateGraph - 10
                app.endDateGraph = app.startDateGraph + 10;
                msgbox('The start date of the data graphed cannot be the same as or later than the end date','Error','error');
            end
            
            if app.endDateGraph <= 10
                app.endDateGraph = 11;
                msgbox('The graph cannot show less than 10 data points');
            end
            
            xlim(app.UIAxes,[app.startDateGraph app.endDateGraph])
            
            if app.isGraphDemand
                plot(app.UIAxes,app.demandData,'-r');
            end
            if app.isGraphSolar
                plot(app.UIAxes,app.solarData,'-y');
            end
            if app.isGraphTemperature
                plot(app.UIAxes,app.tempData,'-b');
            end
        end

        % Value changing function: MinimumSlider
        function MinimumSliderValueChanging(app, event)
            app.graphYMin = event.Value;
            
            if app.graphYMin > app.graphYMax - 5
                app.graphYMin = app.graphYMax - 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Value changing function: MaximumSlider
        function MaximumSliderValueChanging(app, event)
            app.graphYMax = event.Value;
            
            if app.graphYMax < app.graphYMin + 5
                app.graphYMax = app.graphYMin + 5;
                msgbox('The minimum data point graphed cannot be larger than the maximum data point graphed','Error','error');
            end
            
            ylim(app.UIAxes, [(app.dataMax*(app.graphYMin/100)) (app.dataMax*(app.graphYMax/100))]);
        end

        % Selection changed function: TemperatureUnitsButtonGroup
        function TemperatureUnitsButtonGroupSelectionChanged(app, event)
            app.tempUnit = app.TemperatureUnitsButtonGroup.SelectedObject;
            if app.tempUnit == 'Fahrenheit'
                app.tempData = fahrenheitToCelsius(app,app.tempData);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcometoSASULabel = uilabel(app.HomeTab);
            app.WelcometoSASULabel.Text = 'Welcome to SASU';
            app.HomesteadFLButton_2 = uibutton(app.HomeTab, 'push');
            app.HomesteadFLButton_2.Text = 'Homestead, FL';
            app.ChelanCountyWAButton_2 = uibutton(app.HomeTab, 'push');
            app.ChelanCountyWAButton_2.Text = 'Chelan County, WA';
            app.GreatPlainsWestMTButton_2 = uibutton(app.HomeTab, 'push');
            app.GreatPlainsWestMTButton_2.Text = 'Great Plains West, MT';
            app.DemoModeLabel_2 = uilabel(app.HomeTab);
            app.DemoModeLabel_2.Text = 'Demo Mode';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'NSF_Image.jpg';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = 'UMICH_Image.jpg';
            app.TextArea_3 = uitextarea(app.HomeTab);
            app.TextArea_3.Value = {'Sustainable Alternatives for Substation Upgrade, or SASU, is a tool that allows energy grid managers to simulate PV-BESS installations compared with substation upgrades. The tool will optimize for the highest Net Present Value (NPV), both in traditional infrastructure upgrade as well as PV-BESS upgrade to user-defined specifications.  The user can also view BESS charging-discharging algorithms, utility demand, annual revenue, and more.'};
            app.WhatisSASULabel = uilabel(app.HomeTab);
            app.WhatisSASULabel.Text = {'What is SASU?'; ''};
            app.TutorialLabel = uilabel(app.HomeTab);
            app.TutorialLabel.Text = 'Tutorial';
            app.TabGroup2 = uitabgroup(app.HomeTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Image3 = uiimage(app.Tab);
            app.Image3.ImageSource = '1 Hour Optimization.jpg';
            app.TextArea_4 = uitextarea(app.Tab);
            app.TextArea_4.Value = {'Optimize a PV+BESS Installation for your potential development and compare with traditional infrastructure upgrade.'};
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.Image4 = uiimage(app.Tab2);
            app.Image4.ImageSource = 'optimizeITCOnNPV.jpg';
            app.HTML = uihtml(app.HomeTab);
            app.HTML.HTMLSource = 'GUI_Demonstration_Video.html';
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.LoadSolarDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadSolarDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSolarDataButtonPushed, true);
            app.LoadSolarDataButton.Text = 'Load Solar Data';
            app.LoadDemandDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadDemandDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemandDataButtonPushed, true);
            app.LoadDemandDataButton.Text = 'Load Demand Data';
            app.LoadTemperatureDataButton = uibutton(app.InputDataTab, 'push');
            app.LoadTemperatureDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTemperatureDataButtonPushed, true);
            app.LoadTemperatureDataButton.Text = 'Load Temperature Data';
            app.LoadDataLabel = uilabel(app.InputDataTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.DataTimeIncrementButtonGroup = uibuttongroup(app.InputDataTab);
            app.DataTimeIncrementButtonGroup.Title = 'Data Time Increment';
            app.MinutesButton = uitogglebutton(app.DataTimeIncrementButtonGroup);
            app.MinutesButton.Text = 'Minutes';
            app.MinutesButton.Value = true;
            app.HoursButton = uitogglebutton(app.DataTimeIncrementButtonGroup);
            app.HoursButton.Text = 'Hours';
            app.TextArea_2 = uitextarea(app.InputDataTab);
            app.TextArea_2.Value = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit. Aenean ullamcorper, lacus a ornare malesuada, enim massa efficitur augue, eu commodo nibh nisi id nisl. Nunc sed libero vel metus tempor maximus ultricies eget lorem. Cras est nisl, gravida id porta sed, cursus eget felis. Proin luctus metus et lorem blandit, sit amet congue orci rutrum. Nam gravida, odio et ultricies dignissim, risus est ultrices augue, eget eleifend ante lorem ac lectus. Suspendisse ut sagittis augue, sed pulvinar neque. Curabitur justo lorem, maximus eget pulvinar eu, tincidunt non quam. Mauris posuere lectus in sodales cursus. Curabitur blandit magna id leo maximus accumsan. Phasellus non gravida orci. Duis vehicula faucibus diam et congue.'};
            app.SolarArraySizeMWEditFieldLabel = uilabel(app.InputDataTab);
            app.SolarArraySizeMWEditFieldLabel.Text = 'Solar Array Size (MW)';
            app.SolarArraySizeMWEditField = uieditfield(app.InputDataTab, 'numeric');
            app.PredictedYearlyLoadGrowthLabel = uilabel(app.InputDataTab);
            app.PredictedYearlyLoadGrowthLabel.Text = 'Predicted Yearly Load Growth (%)';
            app.PredictedYearlyLoadGrowthEditField = uieditfield(app.InputDataTab, 'numeric');
            app.SolarGenerationCheckBox = uicheckbox(app.InputDataTab);
            app.SolarGenerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolarGenerationCheckBoxValueChanged, true);
            app.SolarGenerationCheckBox.Text = 'Solar Generation';
            app.ElectricityDemandCheckBox = uicheckbox(app.InputDataTab);
            app.ElectricityDemandCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityDemandCheckBoxValueChanged, true);
            app.ElectricityDemandCheckBox.Text = 'Electricity Demand';
            app.TemperatureCheckBox = uicheckbox(app.InputDataTab);
            app.TemperatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemperatureCheckBoxValueChanged, true);
            app.TemperatureCheckBox.Text = 'Temperature';
            app.DatatoGraphLabel = uilabel(app.InputDataTab);
            app.DatatoGraphLabel.Text = 'Data to Graph';
            app.StartDatetographSliderLabel = uilabel(app.InputDataTab);
            app.StartDatetographSliderLabel.Text = {'Start Date'; '(to graph)'};
            app.StartDatetographSlider = uislider(app.InputDataTab);
            app.StartDatetographSlider.Limits = [0 365];
            app.StartDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @StartDatetographSliderValueChanging, true);
            app.EndDatetographSliderLabel = uilabel(app.InputDataTab);
            app.EndDatetographSliderLabel.Text = {'End Date'; '(to graph)'};
            app.EndDatetographSlider = uislider(app.InputDataTab);
            app.EndDatetographSlider.Limits = [0 365];
            app.EndDatetographSlider.ValueChangingFcn = createCallbackFcn(app, @EndDatetographSliderValueChanging, true);
            app.MaximumSliderLabel = uilabel(app.InputDataTab);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.InputDataTab);
            app.MaximumSlider.ValueChangingFcn = createCallbackFcn(app, @MaximumSliderValueChanging, true);
            app.MinimumSliderLabel = uilabel(app.InputDataTab);
            app.MinimumSliderLabel.Text = 'Minimum';
            app.MinimumSlider = uislider(app.InputDataTab);
            app.MinimumSlider.ValueChangingFcn = createCallbackFcn(app, @MinimumSliderValueChanging, true);
            app.TemperatureUnitsButtonGroup = uibuttongroup(app.InputDataTab);
            app.TemperatureUnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TemperatureUnitsButtonGroupSelectionChanged, true);
            app.TemperatureUnitsButtonGroup.Title = 'Temperature Units';
            app.CelsiusButton = uitogglebutton(app.TemperatureUnitsButtonGroup);
            app.CelsiusButton.Text = 'Celsius';
            app.CelsiusButton.Value = true;
            app.FahrenheitButton = uitogglebutton(app.TemperatureUnitsButtonGroup);
            app.FahrenheitButton.Text = 'Fahrenheit';
            app.UIAxes = uiaxes(app.InputDataTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SystemSpecificationsTab = uitab(app.TabGroup);
            app.SystemSpecificationsTab.Title = 'System Specifications';
            app.ESSAlgorithmButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.ESSAlgorithmButtonGroup.Title = 'ESS Algorithm';
            app.TimeBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.TimeBasedButton.Text = 'Time-Based';
            app.TimeBasedButton.Value = true;
            app.LoadBasedButton = uitogglebutton(app.ESSAlgorithmButtonGroup);
            app.LoadBasedButton.Text = 'Load-Based';
            app.BatteryReplacementButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.BatteryReplacementButtonGroup.Title = 'Battery Replacement';
            app.YearsButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.YearsButton.Text = 'Years';
            app.YearsButton.Value = true;
            app.CyclesButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.CyclesButton.Text = 'Cycles';
            app.MinimumSOHButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.MinimumSOHButton.Text = 'Minimum SOH';
            app.ThroughputMWhButton = uitogglebutton(app.BatteryReplacementButtonGroup);
            app.ThroughputMWhButton.Text = 'Throughput (MWh)';
            app.ESSDepthofDischargeLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDepthofDischargeLabel.Text = 'ESS Depth of Discharge (%)';
            app.ESSDepthofDischargeEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSDurationhoursEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSDurationhoursEditFieldLabel.Text = 'ESS Duration (hours)';
            app.ESSDurationhoursEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSInitialEnergyofMaximumEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSInitialEnergyofMaximumEditFieldLabel.Text = 'ESS Initial Energy (% of Maximum)';
            app.ESSInitialEnergyofMaximumEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.SubstationPlannedRatingMWEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.SubstationPlannedRatingMWEditFieldLabel.Text = 'Substation Planned Rating (MW)';
            app.SubstationPlannedRatingMWEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.UnitsSwitchLabel = uilabel(app.SystemSpecificationsTab);
            app.UnitsSwitchLabel.Text = 'Units';
            app.UnitsSwitch = uiswitch(app.SystemSpecificationsTab, 'slider');
            app.UnitsSwitch.Items = {'MW', 'KW'};
            app.UnitsSwitch.Value = 'MW';
            app.EditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.EditField_2Label = uilabel(app.SystemSpecificationsTab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.EditField_3Label = uilabel(app.SystemSpecificationsTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.EditField2Label = uilabel(app.SystemSpecificationsTab);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlyESSCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlyESSCapacityLossEditFieldLabel.Text = 'Yearly ESS Capacity Loss (%)';
            app.YearlyESSCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearlySolarCapacityLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearlySolarCapacityLossEditFieldLabel.Text = 'Yearly Solar Capacity Loss (%)';
            app.YearlySolarCapacityLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.MonthlyESSchargeLossEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.MonthlyESSchargeLossEditFieldLabel.Text = 'Monthly ESS charge Loss (%)';
            app.MonthlyESSchargeLossEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.InverterEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.InverterEfficiencyLabel.Text = 'Inverter Efficiency (%)';
            app.InverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.DCDCConverterEfficiencyEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.DCDCConverterEfficiencyEditFieldLabel.Text = 'DC-DC Converter Efficiency (%)';
            app.DCDCConverterEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.ESSRoundTripEfficiencyLabel = uilabel(app.SystemSpecificationsTab);
            app.ESSRoundTripEfficiencyLabel.Text = 'ESS Round-Trip Efficiency (%)';
            app.ESSRoundTripEfficiencyEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.YearsperHardwareReplacementEditFieldLabel = uilabel(app.SystemSpecificationsTab);
            app.YearsperHardwareReplacementEditFieldLabel.Text = 'Years per Hardware Replacement';
            app.YearsperHardwareReplacementEditField = uieditfield(app.SystemSpecificationsTab, 'numeric');
            app.HardwareReplacementButtonGroup = uibuttongroup(app.SystemSpecificationsTab);
            app.HardwareReplacementButtonGroup.Title = 'Hardware Replacement';
            app.YearsButton_2 = uitogglebutton(app.HardwareReplacementButtonGroup);
            app.YearsButton_2.Text = 'Years';
            app.YearsButton_2.Value = true;
            app.ThroughputMWhButton_2 = uitogglebutton(app.HardwareReplacementButtonGroup);
            app.ThroughputMWhButton_2.Text = 'Throughput (MWh)';
            app.CostVariablesTab = uitab(app.TabGroup);
            app.CostVariablesTab.Title = 'Cost Variables';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SliderLabel = uilabel(app.ResultsTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ResultsTab);
            app.Slider.Limits = [0 365];
            app.Slider2Label = uilabel(app.ResultsTab);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.ResultsTab);
            app.Slider2.Limits = [0 365];
            app.AboutthistoolTab = uitab(app.TabGroup);
            app.AboutthistoolTab.Title = 'About this tool';
            app.ContributersPanel = uipanel(app.AboutthistoolTab);
            app.ContributersPanel.Title = 'Contributers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentGuiHomeTab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end